classdef allModels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InputsPanel                 matlab.ui.container.Panel
        SeverityscoreDropDownLabel  matlab.ui.control.Label
        Severity                    matlab.ui.control.DropDown
        Input1                      matlab.ui.control.NumericEditField
        Input2                      matlab.ui.control.NumericEditField
        Input3                      matlab.ui.control.NumericEditField
        Input4                      matlab.ui.control.NumericEditField
        Input5                      matlab.ui.control.NumericEditField
        VarName1                    matlab.ui.control.EditField
        VarName2                    matlab.ui.control.EditField
        VarName3                    matlab.ui.control.EditField
        VarName4                    matlab.ui.control.EditField
        VarName5                    matlab.ui.control.EditField
        UnitsLabel                  matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        TreatmenttypeLabel          matlab.ui.control.Label
        TreatmentType               matlab.ui.control.DropDown
        PredictionPanel             matlab.ui.container.Panel
        PredictionGraph             matlab.ui.control.UIAxes
        ImprovementEditFieldLabel   matlab.ui.control.Label
        Improvement                 matlab.ui.control.EditField
        AccuracyPanel               matlab.ui.container.Panel
        AccuracyEditFieldLabel      matlab.ui.control.Label
        Accuracy                    matlab.ui.control.NumericEditField
        RMSEEditFieldLabel          matlab.ui.control.Label
        RMSE                        matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Value changed function: Severity
        function SeverityValueChanged(app, event)
            value = app.Severity.Value;
            
            if isequal(value, 'oSCORAD')
                app.VarName1.Value = 'VEGF';
                app.VarName2.Value = 'IL7';
                app.VarName3.Value = 'TNFa';
                app.VarName4.Value = 'MIP1b';
                app.VarName5.Value = 'IL10';
            elseif isequal(value, 'SCORAD')
                app.VarName1.Value = 'VEGF';
                app.VarName2.Value = 'IL18';
                app.VarName3.Value = 'IL7';
                app.VarName4.Value = 'CTACK';
                app.VarName5.Value = 'IL10';
            elseif isequal(value, 'EASI')
                app.VarName1.Value = 'IL7';
                app.VarName2.Value = 'TNFa';
                app.VarName3.Value = 'IL31';
                app.VarName4.Value = 'MIP1b';
                app.VarName5.Value = 'IL10';
            else
                app.VarName1.Value = 'Error';
                app.VarName2.Value = 'Error';
                app.VarName3.Value = 'Error';
                app.VarName4.Value = 'Error';
                app.VarName5.Value = 'Error';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.SeverityscoreDropDownLabel = uilabel(app.InputsPanel);
            app.SeverityscoreDropDownLabel.Text = 'Severity score';
            app.Severity = uidropdown(app.InputsPanel);
            app.Severity.Items = {'oSCORAD', 'SCORAD', 'EASI'};
            app.Severity.ValueChangedFcn = createCallbackFcn(app, @SeverityValueChanged, true);
            app.Severity.Value = 'oSCORAD';
            app.Input1 = uieditfield(app.InputsPanel, 'numeric');
            app.Input2 = uieditfield(app.InputsPanel, 'numeric');
            app.Input3 = uieditfield(app.InputsPanel, 'numeric');
            app.Input4 = uieditfield(app.InputsPanel, 'numeric');
            app.Input5 = uieditfield(app.InputsPanel, 'numeric');
            app.VarName1 = uieditfield(app.InputsPanel, 'text');
            app.VarName1.Editable = 'off';
            app.VarName2 = uieditfield(app.InputsPanel, 'text');
            app.VarName2.Editable = 'off';
            app.VarName3 = uieditfield(app.InputsPanel, 'text');
            app.VarName3.Editable = 'off';
            app.VarName4 = uieditfield(app.InputsPanel, 'text');
            app.VarName4.Editable = 'off';
            app.VarName5 = uieditfield(app.InputsPanel, 'text');
            app.VarName5.Editable = 'off';
            app.UnitsLabel = uilabel(app.InputsPanel);
            app.UnitsLabel.Text = 'All values should be entered in pg/ml.';
            app.CalculateButton = uibutton(app.InputsPanel, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.TreatmenttypeLabel = uilabel(app.InputsPanel);
            app.TreatmenttypeLabel.Text = 'Treatment type';
            app.TreatmentType = uidropdown(app.InputsPanel);
            app.TreatmentType.Items = {'AZA', 'MTX'};
            app.TreatmentType.Value = 'AZA';
            app.PredictionPanel = uipanel(app.UIFigure);
            app.PredictionPanel.Title = 'Prediction';
            app.PredictionGraph = uiaxes(app.PredictionPanel);
            title(app.PredictionGraph, 'Title')
            xlabel(app.PredictionGraph, 'X')
            ylabel(app.PredictionGraph, 'Y')
            app.ImprovementEditFieldLabel = uilabel(app.PredictionPanel);
            app.ImprovementEditFieldLabel.Text = 'Improvement';
            app.Improvement = uieditfield(app.PredictionPanel, 'text');
            app.Improvement.Editable = 'off';
            app.AccuracyPanel = uipanel(app.UIFigure);
            app.AccuracyPanel.Title = 'Accuracy';
            app.AccuracyEditFieldLabel = uilabel(app.AccuracyPanel);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.Accuracy = uieditfield(app.AccuracyPanel, 'numeric');
            app.Accuracy.Editable = 'off';
            app.RMSEEditFieldLabel = uilabel(app.AccuracyPanel);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSE = uieditfield(app.AccuracyPanel, 'numeric');
            app.RMSE.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = allModels

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end